classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        Label             matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        Button            matlab.ui.control.Button
    end


    properties (Access = private)
        
    end

    methods (Access = private)
    
        function R = func(~,the0, n, k)
            n0 = 1.000292;
            N = n-1i*k;
            the1=asind(n0*sind(the0)/N);
            M = N*cosd(the1);
            rs = (n0*cosd(the0)-M)/(n0*cosd(the0)+M);
            rp = (n0*M-(M^2+n0^2*sind(the0)^2)*cosd(the0))/(n0*M+(M^2+n0^2*sind(the0)^2)*cosd(the0));
            R(1) = abs(rs)^2;
            R(2) = abs(rp)^2;
        end
        
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            n = app.EditField.Value;
            k = app.EditField_2.Value;
            t = 0:0.1:90;
            num = length(t);
            y1 = zeros([1,num]);
            y2 = zeros([1,num]);
            for j = 1:1:num
                y=func(app,t(j),n,k);
                y1(j)=y(1);
                y2(j)=y(2);
            end
            plot(app.UIAxes,t,y1,'b',t,y2,'r');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '折射率';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '消光系数';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '绘图';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end